classdef Preemptive_SMS_Apps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThesolverhasbeencompletedLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabuLengthEditField             matlab.ui.control.NumericEditField
        TabuLengthEditField_2Label      matlab.ui.control.Label
        TabuIterationEditField          matlab.ui.control.NumericEditField
        TabuIterationEditFieldLabel     matlab.ui.control.Label
        JobPartsRepresentationEditField  matlab.ui.control.EditField
        JobPartsRepresentationLabel     matlab.ui.control.Label
        IterationsLamp                  matlab.ui.control.Lamp
        NumberofPopulationEditField     matlab.ui.control.NumericEditField
        NumberofPopulationEditFieldLabel  matlab.ui.control.Label
        MaximumIterationEditField       matlab.ui.control.NumericEditField
        MaximumIterationEditFieldLabel  matlab.ui.control.Label
        CPUTime                         matlab.ui.control.NumericEditField
        CPUTimeLabel                    matlab.ui.control.Label
        RunningTime                     matlab.ui.control.NumericEditField
        NumberofPopulationEditFieldLabel_2  matlab.ui.control.Label
        BestFitness                     matlab.ui.control.NumericEditField
        MaximumIterationEditFieldLabel_4  matlab.ui.control.Label
        ObjectiveFunctionButtonGroup    matlab.ui.container.ButtonGroup
        TotalWeightedCompletionTimeButton  matlab.ui.control.RadioButton
        TotalWeightedFlowTimeButton_2   matlab.ui.control.RadioButton
        SolveButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DatasetPanel                    matlab.ui.container.Panel
        ProcessingTimeDropDown          matlab.ui.control.DropDown
        ProcessingTimeDropDownLabel     matlab.ui.control.Label
        DatasetInstanceDropDown         matlab.ui.control.DropDown
        DatasetInstanceDropDownLabel    matlab.ui.control.Label
        NumberofJobsDropDown            matlab.ui.control.DropDown
        NumberofJobsDropDownLabel       matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        TSLBAButton                     matlab.ui.control.RadioButton
        TabuSearchTSButton              matlab.ui.control.RadioButton
        LovebirdAlgorithmLBAButton      matlab.ui.control.RadioButton
        PreemptiveSingleMachineSchedulingSolverLabel  matlab.ui.control.Label
        VisualizationGraphPanel         matlab.ui.container.Panel
        GanttChartCheckBox              matlab.ui.control.CheckBox
        ConvergenceGraphCheckBox        matlab.ui.control.CheckBox
        UIAxes_GanttChart               matlab.ui.control.UIAxes
        UIAxes_ConvergenceGraph         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function annotateConvergentGraph(app)
            
        end

        function annotateGanttChart(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            %Import Dataset
            %https://www.youtube.com/watch?v=5nccNUE09o4&list=PLDzzooPYRJ_XNBUkbCkOM-TBWFeO5Ud4I&index=2
            NumberOfJobsValue = str2num(app.NumberofJobsDropDown.Value);
            DatasetInstance = str2num(app.DatasetInstanceDropDown.Value);
            ProcessingTime = str2num(app.ProcessingTimeDropDown.Value);

            %Print Graph
            ConvergentGraph = app.ConvergenceGraphCheckBox.Value;
            GanttChart = app.GanttChartCheckBox.Value;

            %Hide/Unhide Graphs
            %https://www.mathworks.com/matlabcentral/answers/1848408-set-elements-to-hide-visible-matlab-guide

            if ConvergentGraph == 0 %Value from check box
                set(app.UIAxes_ConvergenceGraph, 'Visible', 'off')
                set(app.UIAxes_GanttChart, 'Visible', 'on')
            elseif GanttChart == 0
                set(app.UIAxes_ConvergenceGraph, 'Visible', 'on')
                set(app.UIAxes_GanttChart, 'Visible', 'off')
            end

            if ConvergentGraph == 0 && GanttChart == 0
                set(app.UIAxes_ConvergenceGraph, 'Visible', 'off')
                set(app.UIAxes_GanttChart, 'Visible', 'off')
            elseif ConvergentGraph == 1 && GanttChart == 1
                set(app.UIAxes_ConvergenceGraph, 'Visible', 'on')
                set(app.UIAxes_GanttChart, 'Visible', 'on')
            end

            if ConvergentGraph == 1 && GanttChart == 0
                %Change position of the graph
                set(app.UIAxes_ConvergenceGraph,'Position',[291,66,610,602]);
            elseif ConvergentGraph == 0 && GanttChart == 1
                set(app.UIAxes_GanttChart,'Position',[291,66,610,602]);
            elseif ConvergentGraph == 1 && GanttChart == 1
                set(app.UIAxes_ConvergenceGraph,'Position',[291 382 610 288]);
                set(app.UIAxes_GanttChart,'Position',[292 69 610 291]);
            end

            %Algorithm Parameter
            MaxIt = app.MaximumIterationEditField.Value;
            Population = app.NumberofPopulationEditField.Value;

            %Variable Name
            Job_processing_time = ProcessingTime;
            number_instance = DatasetInstance;
            number_jobs = NumberOfJobsValue;

            MaxGen = MaxIt; %500,1000,8000
            agent = Population; %100,500,4000

            %Import Dataset
            instance_name = ([num2str(number_instance) '-' num2str(number_jobs) 'x' num2str(Job_processing_time)]);
            instance_extension = ([num2str(number_instance) '_' num2str(number_jobs) 'x' num2str(Job_processing_time) '.txt']);
            %assignin('base','instance',importdata(instance_extension))
            instance = importdata(instance_extension);
            %instance = load(instance_extension);

            %Adjust Datasets
            n_job = instance(1,1);
            instance = instance';
            instance = instance(2:end);
            a_all = {};
            instances  = instance;

            for c = 1:3
                a1 = instances(1:10); %(1:(length(instance)/instance(1,1))) ; instances(1:(length(instance)/instance(1,1)))
                instances(1:10)=[];%instances(1:(length(instance)/instance(1,1))) = [];
                a_all(end+1) = {a1};
            end

            processing_time = cell2mat(a_all(1,1));
            p_time = processing_time(1,1);
            release_date_all = cell2mat(a_all(1,2))';
            weight_job_all = cell2mat(a_all(1,3))';

            P_idx = 1:n_job;

            %Index Job Table
            n_square_mat = n_job*p_time;
            [~,~,indexed_fix_before_summation,~,~] = assign_mat_p2(n_job,p_time,release_date_all,weight_job_all);

            %Constructive Heuristics Search Minimum Release Date
            assign_table = indexed_fix_before_summation;
            odd_index = 1:p_time:size(indexed_fix_before_summation,1);
            first_job_part = indexed_fix_before_summation(odd_index,:);
            placed_schedule = {};
            as_placed_mat = {};
            compare_weight = {};
            compare_weight_idx = {};
            weight_best = {};

            while ~isnan(first_job_part.SchedulePlacement(:)==p_time*n_job)
                [value, index] = min(first_job_part.SchedulePlacement(:));
                if isnan(value)
                    break
                end
                [value_same, index_same] = find(first_job_part.SchedulePlacement == value);
                if length(index_same) ~= 1 %jika ukurannya lebih dari satu
                    for g = 1:length(index_same) %menemukan minimum lainnya
                        value_same(g);
                        value_s1 = first_job_part.JobWeight(value_same(g));
                        index_s1 = first_job_part.MatrixIndex(value_same(g));
                        compare_weight{end+1} = value_s1; %dimasukkan variabel
                        compare_weight_idx{end+1} = index_s1;
                    end
                    compare_fix = max(cell2mat(compare_weight));
                    value_idx_f = find(cell2mat(compare_weight) == compare_fix);
                    value_fix = value_same(value_idx_f);

                    if length(value_idx_f) ~= 1
                        placed_schedule(end+1) = {first_job_part.JobIndex(value_fix(1,1))};
                        as_placed_mat(end+1) = {first_job_part.MatrixIndex(value_fix(1,1))+1};
                        first_job_part.SchedulePlacement(value_fix(1,1)) = NaN;

                    elseif length(value_idx_f) == 1
                        placed_schedule(end+1) = {first_job_part.JobIndex(value_fix)};
                        as_placed_mat(end+1) = {first_job_part.MatrixIndex(value_fix)+1};
                        first_job_part.SchedulePlacement(value_fix) = NaN;
                    end
                    compare_weight = {};
                    compare_weight_idx = {};
                    value_fix = 0;
                    compare_fix = 0;
                    value_idx_f = 0;
                else
                    placed_schedule(end+1) = {first_job_part.JobIndex(index)};
                    as_placed_mat(end+1) = {first_job_part.MatrixIndex(index)+1};
                    first_job_part.SchedulePlacement(index) = NaN;
                end
                [~, index_one] = min(first_job_part.SchedulePlacement(:));
                first_job_part.SchedulePlacement(index_one) = first_job_part.SchedulePlacement(index_one) + 1;
            end


            as_placed_mat;
            placed_schedule;

            as_placed = cell2mat(placed_schedule(1));

            % Create Initial Solution
            initial = zeros(agent,n_square_mat);
            b = repelem(1:n_job,p_time);
            [~,uv_initial_idx,~] = unique(b,'first','legacy');
            b(uv_initial_idx(as_placed)) = [];
            for p = 1:agent
                initial(p,:) = [as_placed b(randperm(length(b)))];
            end
            initial;

            % Calculate Objective Function

            evenI = 2:2:size(indexed_fix_before_summation,1);
            oddI = 1:2:size(indexed_fix_before_summation,1);
            lastI = 1:p_time:size(indexed_fix_before_summation,1);

            % reorder matrix to reconstruct original matrix
            second_job_part = indexed_fix_before_summation(lastI,:);

            second_job_part_s = second_job_part(:,[1 2 3 5 6]); %1 2 5]
            ShuffleIndex = (second_job_part.SchedulePlacement) * 0;
            ConstraintShuffle = (second_job_part.SchedulePlacement) * 0;
            second_job_part_s = [second_job_part_s array2table(ShuffleIndex) array2table(ConstraintShuffle)];

            second_job_part_s;
            check_initial = initial;
            second_job_part_shuffle = second_job_part_s;

            c = 0;
            pinalty = 9999999;
            cost = NaN(agent,1);
            for i = 1:agent
                n_initial_solution = i;
                intial_job_check = check_initial(i,:);
                %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                %first job part check
                for j = 1:n_job
                    job_part_check = unique_value(j);
                    time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                    if first_jp_idx(j) >= time_release
                        %second job check
                        %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                        job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                        if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                            c = c + second_jp_idx(j)*job_weight - time_release;
                            cost(i) = c;
                        else
                            cost(i) = pinalty;
                            break
                        end
                    else
                        cost(i) = pinalty;
                        break
                    end
                end
                c = 0;
            end

            % Sorted schedule
            v = {};
            for i = 1:length(placed_schedule)
                number = placed_schedule(i);
                for x = 1:p_time
                    v{end+1} = cell2mat(number);
                end
            end

            v =cell2mat(v);

            % Sorted schedule Cost

            c = 0;
            v_cost = NaN;
            intial_job_check = v;
            %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
            [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
            [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
            %first job part check
            for j = 1:n_job
                job_part_check = unique_value(j);
                time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                if first_jp_idx(j) >= time_release
                    %second job check
                    %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                    job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                    if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                        c = c + second_jp_idx(j)*job_weight - time_release;
                        v_cost = c;
                    else
                        v_cost = pinalty;
                        break
                    end
                else
                    v_cost = pinalty;
                    break
                end
            end

            % Create new initial population with elitism
            for z = 1:(20/100)*agent %(50/100)*agent+1:(75/100)*agent
                min_initial = v;
                min_value = v_cost;
                cost(z,:) = min_value;
                check_initial(z,:) = min_initial;
            end

            pbest = check_initial;
            f_pbest = cost;

            [f_gbest,ind_f] = min(f_pbest);
            gbest = pbest(ind_f,:);
            BestFitIter(1) = f_gbest;

            for z = (20/100)*agent + 1: (50/100)*agent
                min_initial = gbest;
                min_value = f_gbest;
                cost(z,:) = min_value;
                check_initial(z,:) = min_initial;
            end

            % Tournament variabel
            N = agent;
            F = cost;
            S = 2;
            G = n_job;

            tic;
            tStart = cputime;

            % Create either a LBA, TS or TS-LBA, based on selection
            switch app.AlgorithmButtonGroup.SelectedObject.Text
                case 'Lovebird Algorithm (LBA)'

                    for Gen = 1:MaxGen
                        %app.TextNumberIterations.Value = Gen;
                        %30 flap, 30 slide,
                        T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                        [~,idx] = min(F(T),[],2);
                        W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                        Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                        for i = 1:(30/100)*agent
                            BestX_idx = Pop2(i,:);
                            p = randperm(n_square_mat,2);
                            [init_one] = flap_mut(BestX_idx,p);
                            check_initial(i,:) = init_one;
                        end

                        for i = 1:(30/100)*agent
                            n_initial_solution = i;
                            intial_job_check = check_initial(i,:);
                            %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                            [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                            [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                            %first job part check
                            for j = 1:n_job
                                job_part_check = unique_value(j);
                                time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                if first_jp_idx(j) >= time_release
                                    %second job check
                                    %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                    job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                    if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                        c = c + second_jp_idx(j)*job_weight - time_release;
                                        cost(i) = c;
                                    else
                                        cost(i) = pinalty;
                                        break
                                    end
                                else
                                    cost(i) = pinalty;
                                    break
                                end
                            end
                            c = 0;
                        end

                        T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                        [~,idx] = min(F(T),[],2);
                        W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                        Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                        for i = (30/100)*agent+1:2*(30/100)*agent
                            BestX_idx = Pop2(i,:);
                            p = randperm(n_square_mat,2);
                            [initial] = interchange_mut(BestX_idx,p);
                            check_initial(i,:) = initial;
                        end

                        for i = (30/100)*agent+1:2*(30/100)*agent
                            n_initial_solution = i;
                            intial_job_check = check_initial(i,:);
                            %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                            [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                            [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                            %first job part check
                            for j = 1:n_job
                                job_part_check = unique_value(j);
                                time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                if first_jp_idx(j) >= time_release
                                    %second job check
                                    %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                    job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                    if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                        c = c + second_jp_idx(j)*job_weight - time_release;
                                        cost(i) = c;
                                    else
                                        cost(i) = pinalty;
                                        break
                                    end
                                else
                                    cost(i) = pinalty;
                                    break
                                end
                            end
                            c = 0;
                        end

                        T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                        [~,idx] = min(F(T),[],2);
                        W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                        Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                        for i = 2*(30/100)*agent+1:agent
                            BestX_idx = Pop2(i,:);
                            p = randperm(n_square_mat,2);
                            [initial_one] = slide_mut(BestX_idx,p);
                            check_initial(i,:) = initial_one;
                        end

                        for i = 2*(30/100)*agent+1:agent
                            n_initial_solution = i;
                            intial_job_check = check_initial(i,:);
                            %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                            [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                            [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                            %first job part check
                            for j = 1:n_job
                                job_part_check = unique_value(j);
                                time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                if first_jp_idx(j) >= time_release
                                    %second job check
                                    %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                    job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                    if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                        c = c + second_jp_idx(j)*job_weight - time_release;
                                        cost(i) = c;
                                    else
                                        cost(i) = pinalty;
                                        break
                                    end
                                else
                                    cost(i) = pinalty;
                                    break
                                end
                            end
                            c = 0;
                        end


                        %Fitness evaluation
                        for p = 1:agent
                            if cost(p) < f_pbest(p)
                                f_pbest(p) = cost(p);      %Updating the fitness function value of the personal best solution
                                pbest(p,:) = check_initial(p,:);    %Updating the personal best solution
                                if f_pbest(p) < f_gbest
                                    f_gbest = f_pbest(p);   %Updating the fitness function value of the best solution
                                    gbest = pbest(p,:);     %Updating the best solution
                                end
                            end
                        end

                        BestFitIter(Gen) = f_gbest;

                        for z = 1:(20/100)*agent
                            min_initial = gbest;
                            min_value = f_gbest;
                            cost(z,:) = min_value;
                            check_initial(z,:) = min_initial;
                        end

                        %TextNumberIterationsValueChanged(app, event)
                        %best_schedule = gbest;
                        %disp(['Iteration of LBA ' num2str(Gen) ': Best fitness =' num2str(BestFitIter(Gen)) ' Best Schedule =' num2str(best_schedule)]);
                    end

                    %toc
                    tEnd = cputime - tStart;
                    
                    %Plot Convergence Graph and/or Gantt Charts
                    if ConvergentGraph == 1 && GanttChart == 0
                        plot(app.UIAxes_ConvergenceGraph,1:Gen,BestFitIter);
                    elseif ConvergentGraph == 0 && GanttChart == 1
                        [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                        [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                        n_release_col = 1:n_job;

                        release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                        graph_col = [first_jp_idx' second_jp_idx'];

                        width = .75; % vertical width of horizontal bars
                        ypairs = release_column(:,1) + width./[-2,2];
                        y = repelem(ypairs,1,2);
                        x = release_column(:,[2,3,3,2]);

                        x(1:end,1) = x(1:end,1)-1;
                        x(1:end,4) = x(1:end,4)-1;

                        patch(app.UIAxes_GanttChart,x',y','g');

                        set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                        set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                        set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                        %hold on
                        ppp = release_column(end,1) + width./[-2,2];
                        ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                        xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                        %hold off
                    elseif ConvergentGraph == 1 && GanttChart == 1
                        plot(app.UIAxes_ConvergenceGraph,1:Gen,BestFitIter);

                        [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                        [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                        n_release_col = 1:n_job;

                        release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                        graph_col = [first_jp_idx' second_jp_idx'];

                        width = .75; % vertical width of horizontal bars
                        ypairs = release_column(:,1) + width./[-2,2];
                        y = repelem(ypairs,1,2);
                        x = release_column(:,[2,3,3,2]);

                        x(1:end,1) = x(1:end,1)-1;
                        x(1:end,4) = x(1:end,4)-1;

                        patch(app.UIAxes_GanttChart,x',y','g');

                        set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                        set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                        set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                        %hold on
                        ppp = release_column(end,1) + width./[-2,2];
                        ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                        xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                        %hold off
                    end
                    %Display Outputs
                    app.BestFitness.Value = f_gbest;
                    app.RunningTime.Value = toc;%running_time;
                    app.CPUTime.Value = tEnd;%CPU_time;
                    %https://www.mathworks.com/matlabcentral/answers/765631-value-must-be-a-double-scaler
                    app.JobPartsRepresentationEditField.Value = num2str(gbest);
                    if Gen == MaxGen
                        set(app.IterationsLamp, 'Color', [0.00,1.00,0.00])
                        set(app.ThesolverhasbeencompletedLabel, 'Visible', 'on')
                    end
                 case 'Tabu Search (TS)'
                     NumberOfJobsValue = str2num(app.NumberofJobsDropDown.Value);
                     DatasetInstance = str2num(app.DatasetInstanceDropDown.Value);
                     ProcessingTime = str2num(app.ProcessingTimeDropDown.Value);

                     %Print Graph
                     ConvergentGraph = app.ConvergenceGraphCheckBox.Value;
                     GanttChart = app.GanttChartCheckBox.Value;

                     %Hide/Unhide Graphs
                     %https://www.mathworks.com/matlabcentral/answers/1848408-set-elements-to-hide-visible-matlab-guide

                     if ConvergentGraph == 0 %Value from check box
                         set(app.UIAxes_ConvergenceGraph, 'Visible', 'off')
                         set(app.UIAxes_GanttChart, 'Visible', 'on')
                     elseif GanttChart == 0
                         set(app.UIAxes_ConvergenceGraph, 'Visible', 'on')
                         set(app.UIAxes_GanttChart, 'Visible', 'off')
                     end

                     if ConvergentGraph == 0 && GanttChart == 0
                         set(app.UIAxes_ConvergenceGraph, 'Visible', 'off')
                         set(app.UIAxes_GanttChart, 'Visible', 'off')
                     elseif ConvergentGraph == 1 && GanttChart == 1
                         set(app.UIAxes_ConvergenceGraph, 'Visible', 'on')
                         set(app.UIAxes_GanttChart, 'Visible', 'on')
                     end

                     if ConvergentGraph == 1 && GanttChart == 0
                         %Change position of the graph
                         set(app.UIAxes_ConvergenceGraph,'Position',[291,66,610,602]);
                     elseif ConvergentGraph == 0 && GanttChart == 1
                         set(app.UIAxes_GanttChart,'Position',[291,66,610,602]);
                     elseif ConvergentGraph == 1 && GanttChart == 1
                         set(app.UIAxes_ConvergenceGraph,'Position',[291 382 610 288]);
                         set(app.UIAxes_GanttChart,'Position',[292 69 610 291]);
                     end

                     %Algorithm Parameter
                     MaxIt = app.MaximumIterationEditField.Value;
                     Population = app.NumberofPopulationEditField.Value;

                     %Variable Name
                     Job_processing_time = ProcessingTime;
                     number_instance = DatasetInstance;
                     number_jobs = NumberOfJobsValue;

                     MaxGen = MaxIt; %500,1000,8000
                     agent = Population; %100,500,4000

                     %Import Dataset
                     instance_name = ([num2str(number_instance) '-' num2str(number_jobs) 'x' num2str(Job_processing_time)]);
                     instance_extension = ([num2str(number_instance) '_' num2str(number_jobs) 'x' num2str(Job_processing_time) '.txt']);
                     %assignin('base','instance',importdata(instance_extension))
                     instance = importdata(instance_extension);
                     %instance = load(instance_extension);

                     %Adjust Datasets
                     n_job = instance(1,1);
                     instance = instance';
                     instance = instance(2:end);
                     a_all = {};
                     instances  = instance;

                     for c = 1:3
                         a1 = instances(1:10); %(1:(length(instance)/instance(1,1))) ; instances(1:(length(instance)/instance(1,1)))
                         instances(1:10)=[];%instances(1:(length(instance)/instance(1,1))) = [];
                         a_all(end+1) = {a1};
                     end

                     processing_time = cell2mat(a_all(1,1));
                     p_time = processing_time(1,1);
                     release_date_all = cell2mat(a_all(1,2))';
                     weight_job_all = cell2mat(a_all(1,3))';

                     P_idx = 1:n_job;

                     %Index Job Table
                     n_square_mat = n_job*p_time;
                     [~,~,indexed_fix_before_summation,~,~] = assign_mat_p2(n_job,p_time,release_date_all,weight_job_all);

                     %Constructive Heuristics Search Minimum Release Date
                     assign_table = indexed_fix_before_summation;
                     odd_index = 1:p_time:size(indexed_fix_before_summation,1);
                     first_job_part = indexed_fix_before_summation(odd_index,:);
                     placed_schedule = {};
                     as_placed_mat = {};
                     compare_weight = {};
                     compare_weight_idx = {};
                     weight_best = {};

                     while ~isnan(first_job_part.SchedulePlacement(:)==p_time*n_job)
                         [value, index] = min(first_job_part.SchedulePlacement(:));
                         if isnan(value)
                             break
                         end
                         [value_same, index_same] = find(first_job_part.SchedulePlacement == value);
                         if length(index_same) ~= 1 %jika ukurannya lebih dari satu
                             for g = 1:length(index_same) %menemukan minimum lainnya
                                 value_same(g);
                                 value_s1 = first_job_part.JobWeight(value_same(g));
                                 index_s1 = first_job_part.MatrixIndex(value_same(g));
                                 compare_weight{end+1} = value_s1; %dimasukkan variabel
                                 compare_weight_idx{end+1} = index_s1;
                             end
                             compare_fix = max(cell2mat(compare_weight));
                             value_idx_f = find(cell2mat(compare_weight) == compare_fix);
                             value_fix = value_same(value_idx_f);

                             if length(value_idx_f) ~= 1
                                 placed_schedule(end+1) = {first_job_part.JobIndex(value_fix(1,1))};
                                 as_placed_mat(end+1) = {first_job_part.MatrixIndex(value_fix(1,1))+1};
                                 first_job_part.SchedulePlacement(value_fix(1,1)) = NaN;

                             elseif length(value_idx_f) == 1
                                 placed_schedule(end+1) = {first_job_part.JobIndex(value_fix)};
                                 as_placed_mat(end+1) = {first_job_part.MatrixIndex(value_fix)+1};
                                 first_job_part.SchedulePlacement(value_fix) = NaN;
                             end
                             compare_weight = {};
                             compare_weight_idx = {};
                             value_fix = 0;
                             compare_fix = 0;
                             value_idx_f = 0;
                         else
                             placed_schedule(end+1) = {first_job_part.JobIndex(index)};
                             as_placed_mat(end+1) = {first_job_part.MatrixIndex(index)+1};
                             first_job_part.SchedulePlacement(index) = NaN;
                         end
                         [~, index_one] = min(first_job_part.SchedulePlacement(:));
                         first_job_part.SchedulePlacement(index_one) = first_job_part.SchedulePlacement(index_one) + 1;
                     end


                     as_placed_mat;
                     placed_schedule;

                     as_placed = cell2mat(placed_schedule(1));

                     % Create Initial Solution
                     initial = zeros(agent,n_square_mat);
                     b = repelem(1:n_job,p_time);
                     [~,uv_initial_idx,~] = unique(b,'first','legacy');
                     b(uv_initial_idx(as_placed)) = [];
                     for p = 1:agent
                         initial(p,:) = [as_placed b(randperm(length(b)))];
                     end
                     initial;

                     % Calculate Objective Function

                     evenI = 2:2:size(indexed_fix_before_summation,1);
                     oddI = 1:2:size(indexed_fix_before_summation,1);
                     lastI = 1:p_time:size(indexed_fix_before_summation,1);

                     % reorder matrix to reconstruct original matrix
                     second_job_part = indexed_fix_before_summation(lastI,:);

                     second_job_part_s = second_job_part(:,[1 2 3 5 6]); %1 2 5]
                     ShuffleIndex = (second_job_part.SchedulePlacement) * 0;
                     ConstraintShuffle = (second_job_part.SchedulePlacement) * 0;
                     second_job_part_s = [second_job_part_s array2table(ShuffleIndex) array2table(ConstraintShuffle)];

                     second_job_part_s;
                     check_initial = initial;
                     second_job_part_shuffle = second_job_part_s;

                     c = 0;
                     pinalty = 9999999;
                     cost = NaN(agent,1);
                     for i = 1:agent
                         n_initial_solution = i;
                         intial_job_check = check_initial(i,:);
                         %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                         [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                         [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                         %first job part check
                         for j = 1:n_job
                             job_part_check = unique_value(j);
                             time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                             if first_jp_idx(j) >= time_release
                                 %second job check
                                 %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                 job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                 if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                     c = c + second_jp_idx(j)*job_weight - time_release;
                                     cost(i) = c;
                                 else
                                     cost(i) = pinalty;
                                     break
                                 end
                             else
                                 cost(i) = pinalty;
                                 break
                             end
                         end
                         c = 0;
                     end

                     pbest = check_initial;
                     f_pbest = cost;

                     [f_gbest,ind_f] = min(f_pbest);
                     gbest = pbest(ind_f,:);
                     BestFitIter(1) = f_gbest;

                     %% Problem Definition

                     %model = CreateModel();      % Create TSP Model

                     ActionList=CreatePermActionList(p_time*n_job);%CreatePermActionList(2*n_job);    % Action List

                     nAction=numel(ActionList);              % Number of Actions


                     %% Tabu Search Parameters

                     %MaxIt=1000; %MaxGen;                      % Maximum Number of Iterations

                     TL=round(0.5*nAction); %round(0.5*nAction);      % Tabu Length, 0.25 bisa cepet ketemu

                     %% Initialization

                     % Create Empty Individual Structure
                     empty_individual.Position=[];
                     empty_individual.Cost=[];

                     % Array to Hold Best Costs
                     BestCost=zeros(MaxIt,1);
                     %BestSchedule =zeros(MaxIt,2*n_job);
                     BestSchedule = {}';

                     % Initialize Action Tabu Counters
                     TC=zeros(nAction,1);


                     %% Initialization Tabu Search

                     % Create Initial Solution
                     sol=empty_individual;
                     sol.Position=gbest;%v;
                     sol.Cost=f_gbest;%v_cost;

                     % Initialize Best Solution Ever Found
                     BestSol=sol;

                     %% Tabu Search Main Loop
                     tic;
                     tStart = cputime;

                     for it=1:MaxIt %MaxGen

                         bestnewsol.Cost=inf;

                         % Apply Actions
                         for i=1:nAction
                             if TC(i)==0
                                 newsol.Position=DoAction(sol.Position,ActionList{i});
                                 [unique_value,first_jp_idx,~] = unique(newsol.Position,'first','legacy');
                                 [~,second_jp_idx,~] = unique(newsol.Position,'last','legacy');
                                 %first job part check
                                 for j = 1:n_job
                                     job_part_check = unique_value(j);
                                     time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                     if first_jp_idx(j) >= time_release
                                         %second job check
                                         %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                         job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                         if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                             c = c + second_jp_idx(j)*job_weight - time_release;
                                             costs = c;
                                         else
                                             pinalty = 9999999;
                                             costs = pinalty;
                                             break
                                         end
                                     else
                                         pinalty = 9999999;
                                         costs = pinalty;
                                         break
                                     end
                                 end
                                 c = 0;
                                 newsol.Cost=costs;
                                 newsol.ActionIndex=i;

                                 if newsol.Cost<=bestnewsol.Cost
                                     bestnewsol=newsol;
                                 end
                             end
                         end

                         % Update Current Solution
                         sol=bestnewsol;

                         % Update Tabu List
                         for i=1:nAction
                             if i==bestnewsol.ActionIndex
                                 TC(i)=TL;               % Add To Tabu List
                             else
                                 TC(i)=max(TC(i)-1,0);   % Reduce Tabu Counter, max
                             end
                         end

                         % Update Best Solution Ever Found
                         if sol.Cost<=BestSol.Cost
                             BestSol=sol;
                         end

                         % Save Best Cost Ever Found
                         BestCost(it)=BestSol.Cost;
                         BestSchedule(it) = mat2cell(BestSol.Position,1);
                         %abc = cell2mat(BestSol.Position,1);
                         abc = cell2mat(BestSchedule(it));

                         %BestSchedule = BestSchedule';
                         %f_gbest = min(BestCost);
                         %[f_pbestt,ind_f] = min(BestCost);
                         %gbest = cell2mat(BestSchedule); %pbest(ind_f,:);


                         BestFitIter(it) = BestCost(it);

                         %best_schedule = gbest;

                         % Show Iteration Information
                         %disp(['Iteration of TS ' num2str(it) ': Best Cost = ' num2str(BestCost(it)) ' Best Schedule =' num2str(abc)]);
                         %disp(['Iteration of TS ' num2str(it) ': Best Cost = ' num2str(BestCost(it))]);% ' Best Schedule =' num2str(abc)]);
                     end
                     %toc;
                     tEnd = cputime - tStart;

                     %Plot Convergence Graph and/or Gantt Charts
                     if ConvergentGraph == 1 && GanttChart == 0
                         plot(app.UIAxes_ConvergenceGraph,1:it,BestFitIter);
                     elseif ConvergentGraph == 0 && GanttChart == 1
                         [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                         [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                         n_release_col = 1:n_job;

                         release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                         graph_col = [first_jp_idx' second_jp_idx'];

                         width = .75; % vertical width of horizontal bars
                         ypairs = release_column(:,1) + width./[-2,2];
                         y = repelem(ypairs,1,2);
                         x = release_column(:,[2,3,3,2]);

                         x(1:end,1) = x(1:end,1)-1;
                         x(1:end,4) = x(1:end,4)-1;

                         patch(app.UIAxes_GanttChart,x',y','g');

                         set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                         set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                         set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                         %hold on
                         ppp = release_column(end,1) + width./[-2,2];
                         ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                         xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                         %hold off
                     elseif ConvergentGraph == 1 && GanttChart == 1
                         plot(app.UIAxes_ConvergenceGraph,1:it,BestFitIter);

                         [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                         [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                         n_release_col = 1:n_job;

                         release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                         graph_col = [first_jp_idx' second_jp_idx'];

                         width = .75; % vertical width of horizontal bars
                         ypairs = release_column(:,1) + width./[-2,2];
                         y = repelem(ypairs,1,2);
                         x = release_column(:,[2,3,3,2]);

                         x(1:end,1) = x(1:end,1)-1;
                         x(1:end,4) = x(1:end,4)-1;

                         patch(app.UIAxes_GanttChart,x',y','g');

                         set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                         set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                         set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                         %hold on
                         ppp = release_column(end,1) + width./[-2,2];
                         ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                         xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                         %hold off
                     end
                     %Display Outputs
                     app.BestFitness.Value = BestSol.Cost;
                     app.RunningTime.Value = toc;%running_time;
                     app.CPUTime.Value = tEnd;%CPU_time;
                     abc = cell2mat(mat2cell(BestSol.Position,1));
                     %https://www.mathworks.com/matlabcentral/answers/765631-value-must-be-a-double-scaler
                     app.JobPartsRepresentationEditField.Value = num2str(abc);
                     if it == MaxGen
                         set(app.IterationsLamp, 'Color', [0.00,1.00,0.00])
                         set(app.ThesolverhasbeencompletedLabel, 'Visible', 'on')
                     end
                 case 'TS-LBA'
                     %% Problem Definition

                     %model = CreateModel();      % Create TSP Model

                     ActionList=CreatePermActionList(p_time*n_job);%CreatePermActionList(2*n_job);    % Action List

                     nAction=numel(ActionList);              % Number of Actions


                     %% Tabu Search Parameters

                     %MaxIt=MaxIt;%MaxGen/4; %MaxGen;                      % Maximum Number of Iterations

                     TL=round(0.5*nAction); %round(0.5*nAction);      % Tabu Length, 0.25 bisa cepet ketemu

                     %% Initialization

                     % Create Empty Individual Structure
                     empty_individual.Position=[];
                     empty_individual.Cost=[];

                     % Array to Hold Best Costs
                     BestCost=zeros(MaxIt,1);
                     %BestSchedule =zeros(MaxIt,2*n_job);
                     BestSchedule = {}';

                     % Initialize Action Tabu Counters
                     TC=zeros(nAction,1);

                     tic;
                     tStart = cputime;
                     for Gen = 1:MaxGen
                         %1 3 5 2 10 9 4 6 8 7 ==> 1484
                         %intial_job_check = [1 3 5 2 10 9 4 6 8 7]

                         %30 flap, 30 slide,
                         T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                         [~,idx] = min(F(T),[],2);
                         W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                         Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                         for i = 1:(30/100)*agent
                             BestX_idx = Pop2(i,:);
                             p = randperm(n_square_mat,2);
                             [init_one] = flap_mut(BestX_idx,p);
                             check_initial(i,:) = init_one;
                         end

                         for i = 1:(30/100)*agent
                             n_initial_solution = i;
                             intial_job_check = check_initial(i,:);
                             %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                             [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                             [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                             %first job part check
                             for j = 1:n_job
                                 job_part_check = unique_value(j);
                                 time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                 if first_jp_idx(j) >= time_release
                                     %second job check
                                     %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                     job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                     if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                         c = c + second_jp_idx(j)*job_weight - time_release;
                                         cost(i) = c;
                                     else
                                         cost(i) = pinalty;
                                         break
                                     end
                                 else
                                     cost(i) = pinalty;
                                     break
                                 end
                             end
                             c = 0;
                         end

                         T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                         [~,idx] = min(F(T),[],2);
                         W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                         Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                         for i = (30/100)*agent+1:2*(30/100)*agent
                             BestX_idx = Pop2(i,:);
                             p = randperm(n_square_mat,2);
                             [initial] = interchange_mut(BestX_idx,p);
                             check_initial(i,:) = initial;
                         end

                         for i = (30/100)*agent+1:2*(30/100)*agent
                             n_initial_solution = i;
                             intial_job_check = check_initial(i,:);
                             %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                             [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                             [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                             %first job part check
                             for j = 1:n_job
                                 job_part_check = unique_value(j);
                                 time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                 if first_jp_idx(j) >= time_release
                                     %second job check
                                     %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                     job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                     if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                         c = c + second_jp_idx(j)*job_weight - time_release;
                                         cost(i) = c;
                                     else
                                         cost(i) = pinalty;
                                         break
                                     end
                                 else
                                     cost(i) = pinalty;
                                     break
                                 end
                             end
                             c = 0;
                         end

                         T = round(rand(2*N,S)*(N-1)+1); % Tournaments
                         [~,idx] = min(F(T),[],2);
                         W = T(sub2ind(size(T),(1:2*N)',idx)); % Winners
                         Pop2 = check_initial(W(1:agent),:); % Assemble Pop2 Winners 1

                         for i = 2*(30/100)*agent+1:agent
                             BestX_idx = Pop2(i,:);
                             p = randperm(n_square_mat,2);
                             [initial_one] = slide_mut(BestX_idx,p);
                             check_initial(i,:) = initial_one;
                         end

                         for i = 2*(30/100)*agent+1:agent
                             n_initial_solution = i;
                             intial_job_check = check_initial(i,:);
                             %intial_job_check = [1 1 3 3 5 5 2 2 10 10 9 9 4 4 6 6 8 8 7 7]
                             [unique_value,first_jp_idx,~] = unique(intial_job_check,'first','legacy');
                             [~,second_jp_idx,~] = unique(intial_job_check,'last','legacy');
                             %first job part check
                             for j = 1:n_job
                                 job_part_check = unique_value(j);
                                 time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                 if first_jp_idx(j) >= time_release
                                     %second job check
                                     %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                     job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                     if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                         c = c + second_jp_idx(j)*job_weight - time_release;
                                         cost(i) = c;
                                     else
                                         cost(i) = pinalty;
                                         break
                                     end
                                 else
                                     cost(i) = pinalty;
                                     break
                                 end
                             end
                             c = 0;
                         end


                         %Fitness evaluation
                         for p = 1:agent
                             if cost(p) < f_pbest(p)
                                 f_pbest(p) = cost(p);      %Updating the fitness function value of the personal best solution
                                 pbest(p,:) = check_initial(p,:);    %Updating the personal best solution
                                 if f_pbest(p) < f_gbest
                                     f_gbest = f_pbest(p);   %Updating the fitness function value of the best solution
                                     gbest = pbest(p,:);     %Updating the best solution
                                 end
                             end
                         end

                         %% Initialization Tabu Search

                         % Create Initial Solution
                         sol=empty_individual;
                         sol.Position=gbest;
                         sol.Cost=f_gbest;

                         % Initialize Best Solution Ever Found
                         BestSol=sol;

                         %% Tabu Search Main Loop

                         for it=1:MaxIt %MaxGen

                             bestnewsol.Cost=inf;

                             % Apply Actions
                             for i=1:nAction
                                 if TC(i)==0
                                     newsol.Position=DoAction(sol.Position,ActionList{i});
                                     [unique_value,first_jp_idx,~] = unique(newsol.Position,'first','legacy');
                                     [~,second_jp_idx,~] = unique(newsol.Position,'last','legacy');
                                     %first job part check
                                     for j = 1:n_job
                                         job_part_check = unique_value(j);
                                         time_release = second_job_part_shuffle.JobReleaseTime(job_part_check);
                                         if first_jp_idx(j) >= time_release
                                             %second job check
                                             %job_constraint = second_job_part_shuffle.ShuffleIndex(job_part_check)
                                             job_weight = second_job_part_shuffle.JobWeight(job_part_check);
                                             if second_jp_idx(j) >= time_release+(p_time-1) %1 %2*job_constraint
                                                 c = c + second_jp_idx(j)*job_weight - time_release;
                                                 costs = c;
                                             else
                                                 pinalty = 9999999;
                                                 costs = pinalty;
                                                 break
                                             end
                                         else
                                             pinalty = 9999999;
                                             costs = pinalty;
                                             break
                                         end
                                     end
                                     c = 0;
                                     newsol.Cost=costs;
                                     newsol.ActionIndex=i;

                                     if newsol.Cost<=bestnewsol.Cost
                                         bestnewsol=newsol;
                                     end
                                 end
                             end

                             % Update Current Solution
                             sol=bestnewsol;

                             % Update Tabu List
                             for i=1:nAction
                                 if i==bestnewsol.ActionIndex
                                     TC(i)=TL;               % Add To Tabu List
                                 else
                                     TC(i)=max(TC(i)-1,0);   % Reduce Tabu Counter, max
                                 end
                             end

                             % Update Best Solution Ever Found
                             if sol.Cost<=BestSol.Cost
                                 BestSol=sol;
                             end

                             % Save Best Cost Ever Found
                             BestCost(it)=BestSol.Cost;
                             BestSchedule(it) = mat2cell(BestSol.Position,1);
                             gbest = cell2mat(BestSchedule(it));
                             f_gbest = BestCost(it);
                             %BestSchedule(it) = BestSol.Position;
                         end

                         BestFitIter(Gen) = f_gbest;

                         for z = 1:(20/100)*agent
                             min_initial = gbest;
                             min_value = f_gbest;
                             cost(z,:) = min_value;
                             check_initial(z,:) = min_initial;
                         end

                         best_schedule = gbest;
                         %disp(['Iteration of LBA ' num2str(Gen) ': Best fitness =' num2str(BestFitIter(Gen)) ' Best Schedule =' num2str(best_schedule)]);
                     end
                     tEnd = cputime - tStart;

                     %Plot Convergence Graph and/or Gantt Charts
                     if ConvergentGraph == 1 && GanttChart == 0
                         plot(app.UIAxes_ConvergenceGraph,1:it,BestFitIter);
                     elseif ConvergentGraph == 0 && GanttChart == 1
                         [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                         [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                         n_release_col = 1:n_job;

                         release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                         graph_col = [first_jp_idx' second_jp_idx'];

                         width = .75; % vertical width of horizontal bars
                         ypairs = release_column(:,1) + width./[-2,2];
                         y = repelem(ypairs,1,2);
                         x = release_column(:,[2,3,3,2]);

                         x(1:end,1) = x(1:end,1)-1;
                         x(1:end,4) = x(1:end,4)-1;

                         patch(app.UIAxes_GanttChart,x',y','g');

                         set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                         set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                         set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                         %hold on
                         ppp = release_column(end,1) + width./[-2,2];
                         ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                         xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                         %hold off
                     elseif ConvergentGraph == 1 && GanttChart == 1
                         plot(app.UIAxes_ConvergenceGraph,1:it,BestFitIter);

                         [~,first_jp_idx,~] = unique(gbest,'first','legacy');
                         [~,second_jp_idx,~] = unique(gbest,'last','legacy');

                         n_release_col = 1:n_job;

                         release_column = [n_release_col' first_jp_idx' second_jp_idx'];

                         graph_col = [first_jp_idx' second_jp_idx'];

                         width = .75; % vertical width of horizontal bars
                         ypairs = release_column(:,1) + width./[-2,2];
                         y = repelem(ypairs,1,2);
                         x = release_column(:,[2,3,3,2]);

                         x(1:end,1) = x(1:end,1)-1;
                         x(1:end,4) = x(1:end,4)-1;

                         patch(app.UIAxes_GanttChart,x',y','g');

                         set(app.UIAxes_GanttChart, 'YGrid', 'on', 'XGrid', 'off')
                         set(app.UIAxes_GanttChart,'YTick',sort(release_column(:,1)))
                         set(app.UIAxes_GanttChart,'XTick',sort(release_column(:,3)))

                         %hold on
                         ppp = release_column(end,1) + width./[-2,2];
                         ylim(app.UIAxes_GanttChart,[0 ppp(1,2)])
                         xline(app.UIAxes_GanttChart,release_column(:,3),'b--')
                         %hold off
                     end
                     %Display Outputs
                     app.BestFitness.Value = BestSol.Cost;
                     app.RunningTime.Value = toc;%running_time;
                     app.CPUTime.Value = tEnd;%CPU_time;
                     abc = cell2mat(mat2cell(BestSol.Position,1));
                     %https://www.mathworks.com/matlabcentral/answers/765631-value-must-be-a-double-scaler
                     app.JobPartsRepresentationEditField.Value = num2str(best_schedule);
                     if it == MaxGen
                         set(app.IterationsLamp, 'Color', [0.00,1.00,0.00])
                         set(app.ThesolverhasbeencompletedLabel, 'Visible', 'on')
                     end
             end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            set(app.IterationsLamp, 'Color', [1.00,0.00,0.00])
            set(app.ThesolverhasbeencompletedLabel, 'Visible', 'off')

            %Plot Convergence Graph and/or Gantt Charts
            plot(app.UIAxes_ConvergenceGraph,0,0);
            plot(app.UIAxes_GanttChart,0,0);
            set(app.UIAxes_GanttChart, 'YGrid', 'off', 'XGrid', 'off')

            %Display Outputs
            app.BestFitness.Value = 0;
            app.RunningTime.Value = 0;%running_time;
            app.CPUTime.Value = 0;%CPU_time;
            
            app.MaximumIterationEditField.Value = 0;
            app.NumberofPopulationEditField.Value = 0;

            %app.NumberofJobsDropDown.Value = '10';
            %app.DatasetInstanceDropDown.Value = '1';
            %app.ProcessingTimeDropDown.Value = '2';
            
            app.JobPartsRepresentationEditField.Value = num2str('');
        end

        % Value changed function: BestFitness
        function BestFitnessValueChanged(app, event)
            value = app.BestFitness.Value;
            
        end

        % Value changed function: RunningTime
        function RunningTimeValueChanged(app, event)
            value = app.RunningTime.Value;
            
        end

        % Value changed function: CPUTime
        function CPUTimeValueChanged(app, event)
            value = app.CPUTime.Value;
            
        end

        % Callback function
        function TextNumberIterationsValueChanged(app, event)
            %value = app.TextNumberIterations.Value;
            while Gen <= MaxGen
                app.TextNumberIterations.Value = Gen;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_ConvergenceGraph = uiaxes(app.UIFigure);
            title(app.UIAxes_ConvergenceGraph, 'Convergence Graph')
            xlabel(app.UIAxes_ConvergenceGraph, 'Iteration')
            ylabel(app.UIAxes_ConvergenceGraph, 'Best fitness')
            zlabel(app.UIAxes_ConvergenceGraph, 'Z')
            app.UIAxes_GanttChart = uiaxes(app.UIFigure);
            title(app.UIAxes_GanttChart, 'Gantt Chart')
            xlabel(app.UIAxes_GanttChart, 'Time Intervals (in minutes)')
            ylabel(app.UIAxes_GanttChart, 'Machine Jobs Sequence')
            zlabel(app.UIAxes_GanttChart, 'Z')
            app.VisualizationGraphPanel = uipanel(app.UIFigure);
            app.VisualizationGraphPanel.Title = 'Visualization Graph';
            app.ConvergenceGraphCheckBox = uicheckbox(app.VisualizationGraphPanel);
            app.ConvergenceGraphCheckBox.Text = 'Convergence Graph';
            app.ConvergenceGraphCheckBox.Value = true;
            app.GanttChartCheckBox = uicheckbox(app.VisualizationGraphPanel);
            app.GanttChartCheckBox.Text = 'Gantt Chart';
            app.GanttChartCheckBox.Value = true;
            app.PreemptiveSingleMachineSchedulingSolverLabel = uilabel(app.UIFigure);
            app.PreemptiveSingleMachineSchedulingSolverLabel.Text = 'Preemptive Single Machine Scheduling Solver';
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.LovebirdAlgorithmLBAButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.LovebirdAlgorithmLBAButton.Text = 'Lovebird Algorithm (LBA)';
            app.LovebirdAlgorithmLBAButton.Value = true;
            app.TabuSearchTSButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.TabuSearchTSButton.Text = 'Tabu Search (TS)';
            app.TSLBAButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.TSLBAButton.Text = 'TS-LBA';
            app.ParameterLabel = uilabel(app.UIFigure);
            app.ParameterLabel.Text = 'Parameter';
            app.DatasetPanel = uipanel(app.UIFigure);
            app.DatasetPanel.Title = 'Dataset';
            app.NumberofJobsDropDownLabel = uilabel(app.DatasetPanel);
            app.NumberofJobsDropDownLabel.Text = 'Number of Jobs';
            app.NumberofJobsDropDown = uidropdown(app.DatasetPanel);
            app.NumberofJobsDropDown.Items = {'10', '20', '30', '40', '50'};
            app.NumberofJobsDropDown.Value = '10';
            app.DatasetInstanceDropDownLabel = uilabel(app.DatasetPanel);
            app.DatasetInstanceDropDownLabel.Text = 'Dataset Instance';
            app.DatasetInstanceDropDown = uidropdown(app.DatasetPanel);
            app.DatasetInstanceDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DatasetInstanceDropDown.Value = '1';
            app.ProcessingTimeDropDownLabel = uilabel(app.DatasetPanel);
            app.ProcessingTimeDropDownLabel.Text = 'Processing Time';
            app.ProcessingTimeDropDown = uidropdown(app.DatasetPanel);
            app.ProcessingTimeDropDown.Items = {'2', '3', '5', '10'};
            app.ProcessingTimeDropDown.Value = '2';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ObjectiveFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.ObjectiveFunctionButtonGroup.Title = 'Objective Function';
            app.TotalWeightedFlowTimeButton_2 = uiradiobutton(app.ObjectiveFunctionButtonGroup);
            app.TotalWeightedFlowTimeButton_2.Text = 'Total Weighted Flow Time';
            app.TotalWeightedFlowTimeButton_2.Value = true;
            app.TotalWeightedCompletionTimeButton = uiradiobutton(app.ObjectiveFunctionButtonGroup);
            app.TotalWeightedCompletionTimeButton.Visible = 'off';
            app.TotalWeightedCompletionTimeButton.Text = 'Total Weighted Completion Time';
            app.MaximumIterationEditFieldLabel_4 = uilabel(app.UIFigure);
            app.MaximumIterationEditFieldLabel_4.Text = 'Best Fitness';
            app.BestFitness = uieditfield(app.UIFigure, 'numeric');
            app.BestFitness.ValueChangedFcn = createCallbackFcn(app, @BestFitnessValueChanged, true);
            app.NumberofPopulationEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NumberofPopulationEditFieldLabel_2.Text = 'Running Time (s)';
            app.RunningTime = uieditfield(app.UIFigure, 'numeric');
            app.RunningTime.ValueChangedFcn = createCallbackFcn(app, @RunningTimeValueChanged, true);
            app.CPUTimeLabel = uilabel(app.UIFigure);
            app.CPUTimeLabel.Text = 'CPU Time (s)';
            app.CPUTime = uieditfield(app.UIFigure, 'numeric');
            app.CPUTime.ValueChangedFcn = createCallbackFcn(app, @CPUTimeValueChanged, true);
            app.MaximumIterationEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIterationEditFieldLabel.Text = 'Maximum Iteration';
            app.MaximumIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPopulationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPopulationEditFieldLabel.Text = 'Number of Population';
            app.NumberofPopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsLamp = uilamp(app.UIFigure);
            app.JobPartsRepresentationLabel = uilabel(app.UIFigure);
            app.JobPartsRepresentationLabel.Text = 'Job Parts Representation';
            app.JobPartsRepresentationEditField = uieditfield(app.UIFigure, 'text');
            app.TabuIterationEditFieldLabel = uilabel(app.UIFigure);
            app.TabuIterationEditFieldLabel.Text = 'Tabu Iteration';
            app.TabuIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TabuLengthEditField_2Label = uilabel(app.UIFigure);
            app.TabuLengthEditField_2Label.Text = 'Tabu Length';
            app.TabuLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image 10 (Traced).png');
            app.ThesolverhasbeencompletedLabel = uilabel(app.UIFigure);
            app.ThesolverhasbeencompletedLabel.Visible = 'off';
            app.ThesolverhasbeencompletedLabel.Text = 'The solver has been completed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preemptive_SMS_Apps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end